classdef NewtonRaphsonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        NewtonRaphsonModificadoLabel  matlab.ui.control.Label
        Table                         matlab.ui.control.Table
        ParametersPanel               matlab.ui.container.Panel
        txtMaxIteration               matlab.ui.control.NumericEditField
        MaxIterationLabel             matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        txtTolerance                  matlab.ui.control.NumericEditField
        ToleranceLabel                matlab.ui.control.Label
        txtStart                      matlab.ui.control.NumericEditField
        X0Label                       matlab.ui.control.Label
        txtFunction                   matlab.ui.control.EditField
        FunctionLabel                 matlab.ui.control.Label
        Graphic                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: Graphic
        function GraphicButtonDown(app, event)
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % 1. Extraer los valores de la interfaz
            % IMPORTANTE: Cambia los nombres (EditField, EditField2...) si los tuyos se llaman diferente
            f_str = app.txtFunction.Value;
            strX0 = app.txtStart.Value;
            strTol = app.txtTolerance.Value;
            strMaxIter = app.txtMaxIteration.Value;
            
            % 2. Validar las entradas usando tu archivo validar_entradas.m
            [esValido, mensaje, x0, tol, maxIter] = validar_entradas(f_str, strX0, strTol, strMaxIter);
            
            if ~esValido
                % Si hay un error, mostrar una ventana emergente en la app
                uialert(app.UIFigure, mensaje, 'Error de Validación');
                return;
            end
            
            % 3. Realizar el cálculo llamando a calcular_newton_raphson.m
            try
                resultados = calcular_newton_raphson(f_str, x0, tol, maxIter);
            catch ME
                uialert(app.UIFigure, ['Error matemático: ' ME.message], 'Error de Ejecución');
                return;
            end
            
            % 4. Actualizar la tabla llamando a generar_tabla.m
            generar_tabla(app.Table, resultados);
            
            % 5. Actualizar la gráfica llamando a graficar_resultado.m
            graficar_resultado(app.Graphic, f_str, resultados);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graphic = uiaxes(app.UIFigure);
            title(app.Graphic, 'Graphic')
            xlabel(app.Graphic, 'X')
            ylabel(app.Graphic, 'Y')
            zlabel(app.Graphic, 'Z')
            app.Graphic.ButtonDownFcn = createCallbackFcn(app, @GraphicButtonDown, true);
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.FunctionLabel = uilabel(app.ParametersPanel);
            app.FunctionLabel.Text = 'Function';
            app.txtFunction = uieditfield(app.ParametersPanel, 'text');
            app.X0Label = uilabel(app.ParametersPanel);
            app.X0Label.Text = 'X0';
            app.txtStart = uieditfield(app.ParametersPanel, 'numeric');
            app.ToleranceLabel = uilabel(app.ParametersPanel);
            app.ToleranceLabel.Text = 'Tolerance';
            app.txtTolerance = uieditfield(app.ParametersPanel, 'numeric');
            app.CalcularButton = uibutton(app.ParametersPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.MaxIterationLabel = uilabel(app.ParametersPanel);
            app.MaxIterationLabel.Text = 'Max Iteration';
            app.txtMaxIteration = uieditfield(app.ParametersPanel, 'numeric');
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'xi'; 'f(xi)'; 'f''(xi)'; 'f''''(xi)'; 'xi+1'};
            app.Table.RowName = {};
            app.NewtonRaphsonModificadoLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonModificadoLabel.Text = 'Newton Raphson Modificado';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'umg.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonRaphsonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end