classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        fxEditField_2       matlab.ui.control.NumericEditField
        fxEditField_2Label  matlab.ui.control.Label
        CalcularButton      matlab.ui.control.Button
        fxEditField         matlab.ui.control.NumericEditField
        fxEditFieldLabel    matlab.ui.control.Label
        CenterPanel         matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        RightPanel          matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % datos solo para ejemplo
            f = @(x) cos(x) - x;
            x_iter = [-2.0, 0.1180, 0.6948, 0.7391, 0.7391];
            %----------

            f_grafica = f;       
            y_iter = f_grafica(x_iter);

            x_min = min(x_iter) - 0.5;
            x_max = max(x_iter) + 0.5;
            x_eje = linspace(x_min, x_max, 1000);
            y_eje = f_grafica(x_eje);
            
            cla(app.UIAxes);

            set(app.UIAxes, 'Color', [0.1 0.1 0.2], 'XColor', [1 1 1], 'YColor', [1 1 1], 'GridColor', [0.5 0.5 0.5], 'GridAlpha', 0.4);

            plot(app.UIAxes, x_eje, y_eje, 'c-', 'LineWidth', 2); hold(app.UIAxes, 'on'); grid(app.UIAxes, 'on');
            plot(app.UIAxes, [x_min, x_max], [0, 0], 'w-', 'LineWidth', 1.2);

            colors = jet(length(x_iter));
            rango_y = max(y_eje) - min(y_eje);
            desp_texto = rango_y * 0.05;

            for i = 1:length(x_iter)
                % curva
                plot(app.UIAxes, x_iter(i), y_iter(i), 'o', 'MarkerFaceColor', colors(i,:), 'MarkerEdgeColor', 'w', 'MarkerSize', 9);
                
                % linea punteada
                plot(app.UIAxes, [x_iter(i), x_iter(i)], [0, y_iter(i)], ':', 'Color', [0.7 0.7 0.7]);
                texto_iter = sprintf('x_{%d} = %.2f', i-1, x_iter(i));
                % etiqueta de iteraciones
                text(app.UIAxes, x_iter(i), y_iter(i) + desp_texto, texto_iter, 'HorizontalAlignment', 'center', 'FontSize', 10, 'FontWeight', 'bold', 'Color', [1 1 1]);
            end

            for i = 1:length(x_iter)-1
                % Conecta los puntos en la curva
                plot(app.UIAxes, [x_iter(i), x_iter(i+1)], [y_iter(i), 0], 'y--', 'LineWidth', 1.5);
            end

            % titulos
            title(app.UIAxes, 'Método de Newton-Raphson Mejorado', 'FontSize', 14, 'Color', [1 1 1]);
            xlabel(app.UIAxes, 'Eje X', 'FontSize', 12, 'Color', [1 1 1]);
            ylabel(app.UIAxes, 'f(x)', 'FontSize', 12, 'Color', [1 1 1]);

            lgd = legend(app.UIAxes, 'f(x) Función', 'Eje X (y=0)', 'Puntos de iteración (X_k)', 'Pasos del método', 'Location', 'Best');
            set(lgd, 'TextColor', [1 1 1], 'Color', [0.15 0.12 0.15], 'EdgeColor', [0.5 0.5 0.5]);
            
            % zoom
            axis(app.UIAxes, [x_min, x_max, min(y_eje) - des_texto, max(y_eje) + des_texto]);
            hold(app.UIAxes, 'off');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fxEditFieldLabel = uilabel(app.LeftPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CalcularButton = uibutton(app.LeftPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.fxEditField_2Label = uilabel(app.LeftPanel);
            app.fxEditField_2Label.Text = 'f''(x)';
            app.fxEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Metodo Newton Raphson - mejorado')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end